classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CompliantVsNonCompliantUIFigure  matlab.ui.Figure
        OpenSerialButton            matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        CloseSerialButton           matlab.ui.control.Button
        RunSquareButton             matlab.ui.control.Button
        TextArea                    matlab.ui.control.TextArea
        TextArea_2                  matlab.ui.control.TextArea
        RunCircleButton             matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        SideLengthEditFieldLabel    matlab.ui.control.Label
        SideLengthEditField         matlab.ui.control.EditField
        CircleRadiusEditFieldLabel  matlab.ui.control.Label
        CircleRadiusEditField       matlab.ui.control.EditField
        SecondstoCompleteEditFieldLabel  matlab.ui.control.Label
        SecondstoCompleteEditField  matlab.ui.control.EditField
        WallDistanceEditFieldLabel  matlab.ui.control.Label
        WallDistanceEditField       matlab.ui.control.EditField
        LaserOnButton               matlab.ui.control.Button
        LaserOffButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Mech = serial('COM6','BaudRate',115200); % Description
        out = "0,0";
        flag = 0;
    end
    
    methods (Access = private)
        
        function Move(app, trajec)
            app.flag = 1;
            t = 0;
            sec = str2double(app.SecondstoCompleteEditField.Value);
            if trajec == "s"
                sec = sec/4;
            end
            tic;
            side = str2double(app.SideLengthEditField.Value);
            radius = str2double(app.CircleRadiusEditField.Value);
            distance = str2double(app.WallDistanceEditField.Value);
            while t<=1*sec
                if trajec == "c"
                    x0 = 0;
                    y0 = 0;
                    xd = radius;
                    yd = 0;
                    vx = (xd-x0)/sec;
                    vy = (yd-y0)/sec;
                    x = x0 + vx*t;
                    y = y0 + vy*t;
                    alpha = atan2(x,distance);
                    gamma = atan2(y,distance);
                    desired = [alpha gamma];
                else
                    x0 = 0;
                    y0 = 0;
                    xd = -side/2;
                    yd = -side/2;
                    vx = (xd-x0)/sec;
                    vy = (yd-y0)/sec;
                    x = x0 + vx*t;
                    y = y0 + vy*t;
                    alpha = atan2(x,distance);
                    gamma = atan2(y,distance);
                    desired = [alpha gamma];
                end
                theta = inv_kinematics(desired);
                app.out = string(theta(1))+','+string(theta(2));
                fprintf(app.Mech,app.out);
                t = toc;

            end
            tic;
            while app.flag == 1
                if trajec == "c"
                    desired = trajCirc(t, sec, radius, distance);
                    theta = inv_kinematics(desired);
                    app.out = string(theta(1))+','+string(theta(2));
                    fprintf(app.Mech,app.out);
                else
                    desired = traj(t, sec, side, distance);
                    theta = inv_kinematics(desired);
                    app.out = string(theta(1))+','+string(theta(2));
                    fprintf(app.Mech,app.out);
                    if t>=4*sec
                        tic;
                    end
                end
                t = toc;
                pause(0.00000000000000000001);
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fopen(app.Mech);
            pause(2);
        end

        % Close request function: CompliantVsNonCompliantUIFigure
        function CompliantVsNonCompliantUIFigureCloseRequest(app, event)
            fclose(app.Mech);
            delete(app)
        end

        % Button pushed function: OpenSerialButton
        function OpenSerialButtonPushed(app, event)
            fopen(app.Mech);
            pause(2);
        end

        % Button pushed function: CloseSerialButton
        function CloseSerialButtonPushed(app, event)
            fclose(app.Mech);
        end

        % Button pushed function: RunSquareButton
        function RunSquareButtonPushed(app, event)
            Move(app, "s");
        end

        % Button pushed function: RunCircleButton
        function RunCircleButtonPushed(app, event)
            Move(app,"c");
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.flag = 0;
            fprintf(app.Mech, "0,0");
        end

        % Button pushed function: LaserOnButton
        function LaserOnButtonPushed(app, event)
            fprintf(app.Mech, "on");
        end

        % Button pushed function: LaserOffButton
        function LaserOffButtonPushed(app, event)
            fprintf(app.Mech, "off");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CompliantVsNonCompliantUIFigure = uifigure('Visible', 'off');
            app.CompliantVsNonCompliantUIFigure.Name = 'Compliant Vs Non-Compliant';
            app.CompliantVsNonCompliantUIFigure.CloseRequestFcn = createCallbackFcn(app, @CompliantVsNonCompliantUIFigureCloseRequest, true);
            app.OpenSerialButton = uibutton(app.CompliantVsNonCompliantUIFigure, 'push');
            app.OpenSerialButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSerialButtonPushed, true);
            app.OpenSerialButton.Text = 'Open Serial';
            app.Image = uiimage(app.CompliantVsNonCompliantUIFigure);
            app.Image.ImageSource = 'logo.png';
            app.CloseSerialButton = uibutton(app.CompliantVsNonCompliantUIFigure, 'push');
            app.CloseSerialButton.ButtonPushedFcn = createCallbackFcn(app, @CloseSerialButtonPushed, true);
            app.CloseSerialButton.Text = 'Close Serial';
            app.RunSquareButton = uibutton(app.CompliantVsNonCompliantUIFigure, 'push');
            app.RunSquareButton.ButtonPushedFcn = createCallbackFcn(app, @RunSquareButtonPushed, true);
            app.RunSquareButton.Text = 'Run Square';
            app.TextArea = uitextarea(app.CompliantVsNonCompliantUIFigure);
            app.TextArea.Value = {'Syrian Arab Republic'; 'Higher Institute For Applied Sciences And Technology'; 'Department of Electromechanical Systems'};
            app.TextArea_2 = uitextarea(app.CompliantVsNonCompliantUIFigure);
            app.TextArea_2.Value = {'Zein Al Abideen Zreik'; 'PhD. Karim Taan'; 'MSc. Nizar Falion'; 'MSc Rama Kassar'};
            app.RunCircleButton = uibutton(app.CompliantVsNonCompliantUIFigure, 'push');
            app.RunCircleButton.ButtonPushedFcn = createCallbackFcn(app, @RunCircleButtonPushed, true);
            app.RunCircleButton.Text = 'Run Circle';
            app.StopButton = uibutton(app.CompliantVsNonCompliantUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SideLengthEditFieldLabel = uilabel(app.CompliantVsNonCompliantUIFigure);
            app.SideLengthEditFieldLabel.Text = {'Side Length'; ''};
            app.SideLengthEditField = uieditfield(app.CompliantVsNonCompliantUIFigure, 'text');
            app.SideLengthEditField.Value = '0.05';
            app.CircleRadiusEditFieldLabel = uilabel(app.CompliantVsNonCompliantUIFigure);
            app.CircleRadiusEditFieldLabel.Text = 'Circle Radius';
            app.CircleRadiusEditField = uieditfield(app.CompliantVsNonCompliantUIFigure, 'text');
            app.CircleRadiusEditField.Value = '0.05';
            app.SecondstoCompleteEditFieldLabel = uilabel(app.CompliantVsNonCompliantUIFigure);
            app.SecondstoCompleteEditFieldLabel.Text = 'Seconds to Complete';
            app.SecondstoCompleteEditField = uieditfield(app.CompliantVsNonCompliantUIFigure, 'text');
            app.SecondstoCompleteEditField.Value = '1';
            app.WallDistanceEditFieldLabel = uilabel(app.CompliantVsNonCompliantUIFigure);
            app.WallDistanceEditFieldLabel.Text = 'Wall Distance';
            app.WallDistanceEditField = uieditfield(app.CompliantVsNonCompliantUIFigure, 'text');
            app.WallDistanceEditField.Value = '0.16';
            app.LaserOnButton = uibutton(app.CompliantVsNonCompliantUIFigure, 'push');
            app.LaserOnButton.ButtonPushedFcn = createCallbackFcn(app, @LaserOnButtonPushed, true);
            app.LaserOnButton.Text = 'Laser On';
            app.LaserOffButton = uibutton(app.CompliantVsNonCompliantUIFigure, 'push');
            app.LaserOffButton.ButtonPushedFcn = createCallbackFcn(app, @LaserOffButtonPushed, true);
            app.LaserOffButton.Text = 'Laser Off';
            % Show the figure after all components are created
            app.CompliantVsNonCompliantUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CompliantVsNonCompliantUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CompliantVsNonCompliantUIFigure)
        end
    end
end